classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ZastosujButton           matlab.ui.control.Button
        WybierzobrazButton       matlab.ui.control.Button
        ZapiszButton             matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        IlowyznaczonychkodLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       img;
    end
    
    methods (Access = private)
        function isLog = DBSCAN(app,centers, eps, minCircles)
            n = size(centers, 1);
            % Distance between each pair of found circles
            Dist = pdist2(centers, centers);
            % Boolean check of passing
            done = false(n, 1);
            % Boolean check if indexed circle is a log
            isLog = true(n, 1);
            
            for i = 1:n
                if ~done(i)
                    done(i) = true;
                    
                    Neighbors = FindNeighbors(i);
                    
                    if numel(Neighbors) < minCircles
                        isLog(i) = false;
                    else
                        k = 1;
                        while k <= numel(Neighbors)
                        j = Neighbors(k);
                    
                        if ~done(j)
                            done(j) = true;
                        
                            Neighbors2 = FindNeighbors(j);
                        
                            % Expand cluster
                            if numel(Neighbors2) >= minCircles
                                Neighbors = [Neighbors, Neighbors2];
                            end
                        end
                    
                        k = k + 1;
                        end
                    end
                end
            end
            
            
            function Neighbors = FindNeighbors(i)
                Neighbors = find(Dist(i, :) <= eps);
            end
        end
        
        function processImage(app, origIm)
            %%% Obraz w odcieniach szarości
            gIm = rgb2gray(origIm);
            Im = gIm;
            
            %%% Filtr medianowy
            medIm = medfilt2(Im, [2 2]);
            Im = medIm;
            
            %%% Filtr Gaussa
            f = fspecial('gaussian', [3 3], 10);
            gaussIm = imfilter(Im, f);
            Im = gaussIm;
            
            %%% Kontrast
            c = 1.4;
            contIm = Im * c;
            contIm(contIm<0) = 0;
            contIm(contIm>1) = 1;
            Im = contIm;
            
            %%% Gamma korekcja
            gamma = 1.13;
            gammaIm = Im.^(1/gamma);
            gammaIm(gammaIm<0) = 0;
            gammaIm(gammaIm>1) = 1;
            Im = gammaIm;
            
            %%% Hough find circles
            [centers, radii] = imfindcircles(Im, [16 50],...
                'Method', 'TwoStage',...
                'Sensitivity', 0.885, 'EdgeThreshold', 0.17);
            
            %%% DBSCAN filters found cirlces and finds logs
            n = size(centers, 1);
            
            isLog = DBSCAN(app, centers, 190, 28);
            
            k = sum(isLog(:) == 1);
            
            
            app.Label.Text = sprintf('%d',k); 
            
            
            arrLog = zeros(k,2);
            arrRad = zeros(k,1);
            index = 1;
            
            for i=1:n
               if (isLog(i) == 1)
                  arrLog(index, 1) = centers(i,1);
                  arrLog(index, 2) = centers(i,2);
                  arrRad(index) = radii(i);
                  index = index + 1;
               end
            end
            
            [level,E] = graythresh(gIm);
            BW = imbinarize(gIm, level);
            
            im_fill = imfill(BW, 'holes');
            im_fill2 = bwareaopen(im_fill, 100);
            
            props = regionprops(im_fill2,'BoundingBox', 'Orientation', 'EulerNumber', 'Area');
            
            index = [];
            
            for i = 1:size(props, 1)
                upper_left_x = props(i).BoundingBox(1);
                upper_left_y = props(i).BoundingBox(2);
                width = props(i).BoundingBox(3);
                height = props(i).BoundingBox(4);     
                ratio = width/height;
                if  props(i).Area > 1600 && ratio <= 5 && ratio >= 3 &&  upper_left_y > 750 && props(i).Orientation > -5 && props(i).Orientation < 5 &&  props(i).EulerNumber == 1 
                    index = [index, i];
                    disp(['ratio = ', num2str(ratio)])
                    disp(['I = ', num2str(i)])
                end
            end
            
            imshow(origIm, 'Parent', app.UIAxes); 
            hold on;
            for i = 1:size(index, 2)
                upper_left_x = props(index(i)).BoundingBox(1); 
                upper_left_y = props(index(i)).BoundingBox(2);
                width = props(index(i)).BoundingBox(3); 
                height = props(index(i)).BoundingBox(4);
                upper_right_x = upper_left_x + width;
                upper_right_y = upper_left_y;
                  
                line([upper_left_x upper_right_x],[upper_left_y upper_right_y],'LineWidth', 3, 'Color','red');
                line([upper_right_x upper_right_x],[upper_right_y upper_right_y+height],'LineWidth', 3, 'Color','red');
                line([upper_right_x upper_left_x],[upper_right_y+height upper_right_y+height],'LineWidth', 3, 'Color','red');
                line([upper_left_x upper_left_x],[upper_right_y+height upper_left_y],'LineWidth', 3, 'Color','red');
            end
            viscircles(arrLog, arrRad, 'EdgeColor', 'b', 'LineWidth', 1,'EnhanceVisibility',false);
            hold off;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WybierzobrazButton
        function WybierzobrazButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select File to Open');
            figure(app.UIFigure);
            fullname = [filepath, filename];
            Im = imresize(double(imread(fullname))/255, [1000 NaN]);
            app.UIAxes.XLim = [0 width(Im)];
            app.UIAxes.YLim = [0 height(Im)];
            imshow(Im, 'Parent', app.UIAxes); 
            app.UIAxes.PositionConstraint = 'innerposition';  
            app.img = Im;
        end

        % Button pushed function: ZastosujButton
        function ZastosujButtonPushed(app, event)
            processImage(app, app.img);
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
%             im = app.UIAxes.Parent;
%             filter = {'*.jpg';'*.png'}
%             [filename, pathname] = uiputfile(filter);
%             newfilename = fullfile(pathname, filename);
%             imwrite(im, newfilename);
               % Create new figure and new axes
                figure;
                axNew = axes; 
      
                % Copy all objects from UIAxes to new axis
                copyobj(app.UIAxes.Children, axNew)
                % Save all parameters of the UIAxes
                uiAxParams = get(app.UIAxes);
                uiAxParamNames = fieldnames(uiAxParams); 
                % Get list of editable params in new axis
                editableParams = fieldnames(set(axNew)); 
                % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
                badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams)); 
                badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition']; 
                uiAxGoodParams = rmfield(uiAxParams,badFields);
                % set editable params on new axes
                set(axNew, uiAxGoodParams)
                axNew.CameraPosition
                fig2Save = ancestor(axNew, 'figure','toplevel');
                set(gcf, 'Position', get(0, 'Screensize'));
                fig2Save.Visible='off';
                filter = {'*.jpg';'*.png'}
                [filename, pathname] = uiputfile(filter);
                newfilename = fullfile(pathname, filename);
                saveas(fig2Save, newfilename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ZastosujButton = uibutton(app.UIFigure, 'push');
            app.ZastosujButton.ButtonPushedFcn = createCallbackFcn(app, @ZastosujButtonPushed, true);
            app.ZastosujButton.Text = 'Zastosuj';
            app.WybierzobrazButton = uibutton(app.UIFigure, 'push');
            app.WybierzobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzobrazButtonPushed, true);
            app.WybierzobrazButton.Text = 'Wybierz obraz';
            app.ZapiszButton = uibutton(app.UIFigure, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Text = 'Zapisz';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IlowyznaczonychkodLabel = uilabel(app.UIFigure);
            app.IlowyznaczonychkodLabel.Text = 'Ilość wyznaczonych kłod:';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, ' ')
            ylabel(app.UIAxes, ' ')
            zlabel(app.UIAxes, ' ')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end